classdef ImageClassifier < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MainLayout                  matlab.ui.container.GridLayout
        GridLayout4                 matlab.ui.container.GridLayout
        ToolstripLayout             matlab.ui.container.GridLayout
        DataSectionLayout           matlab.ui.container.GridLayout
        GridLayout2                 matlab.ui.container.GridLayout
        ImportDatastorefromworkspaceButton  matlab.ui.control.Button
        LoadExampleDataButton       matlab.ui.control.Button
        ImportDatafromFolderButton  matlab.ui.control.Button
        EXPORTLabel                 matlab.ui.control.Label
        PLOTSANDRESULTSLabel        matlab.ui.control.Label
        TRAINLabel                  matlab.ui.control.Label
        MODELLabel                  matlab.ui.control.Label
        DATALabel                   matlab.ui.control.Label
        ExportSectionLayout         matlab.ui.container.GridLayout
        GenerateCodeButton          matlab.ui.control.Button
        ExporttoWorkspaceButton     matlab.ui.control.Button
        PlotSectionLayout           matlab.ui.container.GridLayout
        InterpretButton             matlab.ui.control.Button
        ConfusionMatrixButton       matlab.ui.control.Button
        PredictButton               matlab.ui.control.Button
        TrainSectionLayout          matlab.ui.container.GridLayout
        TrainButton                 matlab.ui.control.Button
        ModelSectionLayout          matlab.ui.container.GridLayout
        GridLayout                  matlab.ui.container.GridLayout
        BuildNewButton              matlab.ui.control.Button
        FromWorkspaceButton         matlab.ui.control.Button
        QuickStartButton            matlab.ui.control.Button
        ChoosePretrainedButton      matlab.ui.control.Button
        MainTabGroup                matlab.ui.container.TabGroup
        DataTab                     matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        ModelTab                    matlab.ui.container.Tab
    end

    
    properties (Access = public)
        % Model (ic.Model)
        % Model implementing the app's backend functionality
        Model

        % Guide (ic.Guide)
        % Contains strings like tooltips and help info.
        Guide

        % AugmentationSettingsWidgets  (struct of widgets)
        % Struct of widgets for data augmentation settings
        AugmentationSettingsWidgets

        % NetworkSettingsWidgets  (struct of widgets)
        % Struct of widgets for network settings
        NetworkSettingsWidgets

        % TrainingOptionssWidgets  (struct of widgets)
        % Struct of widgets for training option settings
        TrainingOptionssWidgets

        % ImagePredictionWidgets  (struct of widgets)
        % Struct of widgets for image inference settings
        ImagePredictionWidgets

        % InterpretabilityWidgets  (struct of widgets)
        % Struct of widgets for image interpretability settings
        InterpretabilityWidgets

        % ImagePreviewLayout  (uigridlayout)
        % Layout for images in preview
        ImagePreviewLayout

        % ClassHistogramAxes  (uiaxes)
        % uiaxes for the histogram showing class distribution
        ClassHistogramAxes

        % AddedTabs  (struct of uitabs)
        % Struct of tabs added to the app after construction
        AddedTabs

        % ConfusionMatrixResults  (struct of data)
        % Struct holding results of the confusion matrix calculation
        ConfusionMatrixResults

        % PredictionResults  (struct of data)
        % Struct holding results of doing inference on data
        PredictionResults

        % InterpretabilityResults  (struct of data)
        % Struct holding results of interpretability techniques
        InterpretabilityResults
    end

    properties (Hidden, Access = public)
        % IsTesting  (logical)
        % Logical for whether we're in testing mode. 
        % 
        % This is used to enable automated testing of more complicated
        % workflows like the creation of modal or blocking dialogs. We want
        % different behavior for user interactions vs tester interactions.
        IsTesting = false
    end
    
    methods (Access = private)
        
        function plotData(app)
            % Plot data imported in the app. Display the high-level summary
            % (number of classes, etc.), the class histogram, and the
            % image augmentation settings.

            [~, cleaner] = showProgressDlg(app, "Loading data...");

            % Clean up old layouts
            delete(app.DataTab.Children);

            % Produce a summary of the data
            summary = summarizeData(app.Model);

            % Make new layouts
            mainDataLayout = uigridlayout(app.DataTab, ...
                RowHeight={'0.5x', '0.5x'}, ...
                ColumnWidth={'0.3x', '0.7x'});

            % Create the different sections of the image visualization.
            createDataSummary(app, mainDataLayout, summary);
            plotClassHistogram(app, mainDataLayout, summary);
            createAugmentationSettings(app, mainDataLayout);

            % Create and store image preview parent; we will need it when
            % augmentation settings are changed.
            previewLayout = uigridlayout(mainDataLayout, [1 1], Padding=0);
            app.ImagePreviewLayout = previewLayout;

            % Plot some example images from the dataset
            plotExampleImages(app, previewLayout);
        end

        function createDataSummary(app, parent, summary)
            % Display a summary of the dataset - number of train/val
            % images, etc.
            
            dataSummaryLayout = uigridlayout(parent, ...
                RowHeight=repmat({'fit'}, 6, 1), ...
                ColumnWidth={'fit', 60}, ...
                Padding=0, ...
                RowSpacing=5);

            titleLabel = uilabel(dataSummaryLayout, Text="Summary", FontWeight="bold");
            titleLabel.Layout.Column = [1 2];

            uilabel(dataSummaryLayout, Text="Training images");
            uilabel(dataSummaryLayout, Text=string(summary.NumTrainingObs));

            uilabel(dataSummaryLayout, Text="Validation images");
            uilabel(dataSummaryLayout, Text=string(summary.NumValidationObs));

            uilabel(dataSummaryLayout, Text="Number of classes");
            uilabel(dataSummaryLayout, Text=string(summary.NumClasses));

            uilabel(dataSummaryLayout, Text="Classes");
            uilabel(dataSummaryLayout, Text=join(summary.ClassLabels, ", "), Tooltip=summary.ClassLabels);

            % Spinner for changing train/val fraction
            uilabel(dataSummaryLayout, Text="Validation fraction");
            uispinner(dataSummaryLayout, Value=app.Model.ValidationFraction, ...
                Step=0.1, Limits=[0.1 0.9], ...
                ValueChangedFcn=@app.handleValidationFractionChanged);
        end

        function plotClassHistogram(app, parent, summary)
            % Plot a histogram of the class distribution of training and
            % validation data.

            % Stacked bar plot
            ax = axes(parent);
            barData = [summary.TrainingClassDistribution', summary.ValidationClassDistribution'];
            bar(ax, barData, "stacked");

            ax.XAxis.TickLabelInterpreter = "none";

            % Axes labels
            ylabel(ax, "Count");
            xlabel(ax, "Class");
            xticklabels(ax, summary.ClassLabels);

            title(ax, "Class Distribution");
            legend(ax, ["Training", "Validation"]);

            app.ClassHistogramAxes = ax;
        end

        function createAugmentationSettings(app, parent)
            % Create widgets for changing data augmentation settings like
            % random rotation.

            % Clean up old widgets
            app.AugmentationSettingsWidgets = struct();

            augSettingsLayout = uigridlayout(parent, ...
                RowHeight=repmat({'fit'}, 6, 1), ...
                ColumnWidth={'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);

            uilabel(augSettingsLayout, Text="Augmentation", FontWeight="bold");
            infoLabel = app.createHelpWidget(augSettingsLayout, "DataAugmentation");
            infoLabel.HorizontalAlignment = "right";

            uilabel(augSettingsLayout, Text="X reflection");
            app.AugmentationSettingsWidgets.RandXReflectionWidget = uiswitch( ...
                augSettingsLayout, Items=["Off", "On"], Value="On", ...
                ValueChangedFcn=@(s,e)app.handleAugmentationSettingWidgetChanged(s,e));

            uilabel(augSettingsLayout, Text="Y reflection");
            app.AugmentationSettingsWidgets.RandYReflectionWidget = uiswitch( ...
                augSettingsLayout, Items=["Off", "On"], Value="On", ...
                ValueChangedFcn=@(s,e)app.handleAugmentationSettingWidgetChanged(s,e));

            uilabel(augSettingsLayout, Text="Rotation");
            app.AugmentationSettingsWidgets.RandRotationSpinner = uispinner( ...
                augSettingsLayout, Value=20, ...
                ValueChangedFcn=@(s,e)app.handleAugmentationSettingWidgetChanged(s,e));

            uilabel(augSettingsLayout, Text="Scale change");
            app.AugmentationSettingsWidgets.RandScaleSpinner = uispinner( ...
                augSettingsLayout, Value=0.0, Step=0.05, Limits=[0 0.99], ...
                ValueChangedFcn=@(s,e)app.handleAugmentationSettingWidgetChanged(s,e));

            helpLink = createHelpLink(app, augSettingsLayout, "Learn more about image augmentation", "ImageAugmentation");
            helpLink.Layout.Column = [1 2];

            % Update augmentation settings with default values
            updateAugmentationSettings(app);
        end

        function plotExampleImages(app, parent)
            % Plot example images from a dataset

            % Separate outer layout to hold the title label
            l = uigridlayout(parent, ...
                RowHeight={'fit', '1x'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0, ...
                RowSpacing=5);
            uilabel(l, Text="Image Preview", FontWeight="bold");

            % Label for holding images
            numImages = 4;

            % Generate preview data
            preview = previewData(app.Model, numImages);
            imagePreviewLayout = uigridlayout(l, ...
                RowHeight={'1x', 'fit'}, ...
                ColumnWidth=repmat({'1x'}, numImages, 1), ...
                Padding=0, ...
                RowSpacing=5);

            for i=1:numImages
                img = preview.PreviewImages{i};

                % Handle cases that render poorly
                if islogical(img)
                    % Image is either 0 or 1 values, which imds outputs as
                    % logical, and uiimage doesn't accept.
                    img = double(img);
                else
                    img = uint8(img);
                end

                im = uiimage(imagePreviewLayout, ImageSource=img, ...
                    ScaleMethod="fit");
                im.Layout.Row = 1;
                im.Layout.Column = i;

                classLabel = uilabel(imagePreviewLayout, Text=preview.PreviewClasses(i));
                classLabel.Layout.Row = 2;
                classLabel.Layout.Column = i;
            end
        end

        function updateAugmentationSettings(app)
            % Update the model-side augmentation settings with values off
            % the widgets.

            widgets = app.AugmentationSettingsWidgets;

            % Pull settings off the widget onto a struct, then use
            % namedargs2cell to convert to a cell array of args.
            augSettings = struct();
            augSettings.RandXReflection = iOnOffToTF(widgets.RandXReflectionWidget.Value);
            augSettings.RandYReflection = iOnOffToTF(widgets.RandYReflectionWidget.Value);
            augSettings.RandRotation = [-widgets.RandRotationSpinner.Value, widgets.RandRotationSpinner.Value];

            % RandScale setting has trickier handling. We expose the
            % setting as a single value with default 0, but
            % imageDataAugmenter wants a [minScale maxScale] pair.
            %
            % If the value on the widget is 0.2, we want to pass values
            % [0.8 1.2].
            augSettings.RandScale = [1-widgets.RandScaleSpinner.Value 1+widgets.RandScaleSpinner.Value];

            args = namedargs2cell(augSettings);
            setAugmentations(app.Model, args{:});

            % Convenience function to convert from the "on"/"off" values on
            % a uiswitch to a logical value.
            function tf = iOnOffToTF(onOff)
                tf = strcmpi(onOff, "on");
            end
        end

        function handleAugmentationSettingWidgetChanged(app, ~, ~)
            % Respond to a change on an augmentation setting widget.

            updateAugmentationSettings(app);

            % Delete old preview images and update with new images
            % corresponding to the new augmentations.
            delete(app.ImagePreviewLayout.Children);
            plotExampleImages(app, app.ImagePreviewLayout);
        end

        function handleValidationFractionChanged(app, ~, evt)
            % Respond to a change on the validation fraction spinner.

            setValidationFraction(app.Model, evt.Value);

            % Delete all old data and re-plot everything.
            plotData(app);
        end

        function createPretrainedNetworkEditor(app)
            % Create the editor for easily choosing an
            % imagePretrainedNetwork for transfer learning.
            
            % Clear out any existing widgets
            delete(app.ModelTab.Children);

            % Outer layout
            outerLayout = uigridlayout(app.ModelTab, ...
                RowHeight={'1x'}, ...
                ColumnWidth={'1x', '1x'});

            leftLayout = uigridlayout(outerLayout, ...
                RowHeight={'fit', 'fit', 'fit'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0);
            rightLayout = uigridlayout(outerLayout, ...
                RowHeight={'fit'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0);

            % Network settings and training options
            createPretrainedNetworkSettings(app, leftLayout);
            createBasicTrainingOptions(app, leftLayout);
            createAdvancedTrainingOptions(app, leftLayout);
            createNetworkTrainingResultsSection(app, rightLayout);

            updateTrainingOptions(app);
        end

        function createQuickTrainNetworkEditor(app)
            % Create the editor for quickly configuring SqueezeNet for
            % transfer learning.

            % Clear out any existing widgets
            delete(app.ModelTab.Children);

            % Outer layout
            outerLayout = uigridlayout(app.ModelTab, ...
                RowHeight={'1x'}, ...
                ColumnWidth={'1x', '1x'});

            leftLayout = uigridlayout(outerLayout, ...
                RowHeight={'fit', 'fit', 'fit'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0);
            rightLayout = uigridlayout(outerLayout, ...
                RowHeight={'fit'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0);

            % Network settings and training options
            createQuickTrainNetworkSettings(app, leftLayout);
            createBasicTrainingOptions(app, leftLayout);
            createAdvancedTrainingOptions(app, leftLayout);
            createNetworkTrainingResultsSection(app, rightLayout);

            updateTrainingOptions(app);
        end

        function createWorkspaceImportNetworkEditor(app, netName)
            % Create the editor for training a network imported from
            % workspace

            % Clear out any existing widgets
            delete(app.ModelTab.Children);

            % Outer layout
            outerLayout = uigridlayout(app.ModelTab, ...
                RowHeight={'1x'}, ...
                ColumnWidth={'1x', '1x'});

            leftLayout = uigridlayout(outerLayout, ...
                RowHeight={'fit', 'fit', 'fit'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0);
            rightLayout = uigridlayout(outerLayout, ...
                RowHeight={'fit'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0);

            % Network settings and training options
            createWorkspaceNetworkSettings(app, leftLayout, netName);
            createBasicTrainingOptions(app, leftLayout);
            createAdvancedTrainingOptions(app, leftLayout);
            createNetworkTrainingResultsSection(app, rightLayout);

            updateTrainingOptions(app);
        end

        function createPretrainedNetworkSettings(app, parent)
            % Create settings for configuring a pretrained network.

            % Network settings
            numRows = 6;
            networkLayout = uigridlayout(parent, ...
                RowHeight=repmat({'fit'}, numRows, 1), ...
                ColumnWidth={'fit', 'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);
            l = uilabel(networkLayout, Text="Network", FontWeight="bold");
            l.Layout.Column = [1 3];

            uilabel(networkLayout, Text="Network");
            app.NetworkSettingsWidgets.NetworkDropdown = uidropdown(networkLayout, ...
                Items=["squeezenet", "googlenet", "inceptionresnetv2", "nasnetlarge"], ...
                ValueChangedFcn=@app.handleNetworkDropdownChanged);
            createHelpWidget(app, networkLayout, "PickingPretrainedNetwork");

            helpLabel = uilabel(networkLayout, ...
                Text="Images are resized to match network input.", FontAngle="italic");
            helpLabel.Layout.Column = [1 3];

            link = createHelpLink(app, networkLayout, "Learn more about pretrained networks", "PretrainedNetwork");
            link.Layout.Column = [1 3];

            % Buttons for viewing network in Analyzer or Deep Network
            % Designer.
            uibutton(networkLayout, Text="Analyze", Tag = "ANALYZE_NETWORKBUTTON", ...
                ButtonPushedFcn=@app.handleAnalyzeButtonPushed);
            uibutton(networkLayout, Text="Visualize", Tag = "VISUALIZE_NETWORKBUTTON", ...
                ButtonPushedFcn=@app.handleDNDButtonPushed);

            % Set a default network
            setNetworkFromPretrained(app.Model, "squeezenet");
        end

        function createQuickTrainNetworkSettings(app, parent)
            % Create settings for quickly configuring the pretrained
            % SqueezeNet.
            %
            % This is just training options; there are no network settings
            % to tweak.

            % Network settings
            numRows = 5;
            networkLayout = uigridlayout(parent, ...
                RowHeight=repmat({'fit'}, numRows, 1), ...
                ColumnWidth={'fit', 'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);
            l = uilabel(networkLayout, Text="Network", FontWeight="bold");
            l.Layout.Column = [1 3];

            uilabel(networkLayout, Text="Network");
            uilabel(networkLayout, Text="SqueezeNet");
            uilabel(networkLayout, Text="");

            helpLabel = uilabel(networkLayout, ...
                Text="Images are resized to match network input.", FontAngle="italic");
            helpLabel.Layout.Column = [1 3];

            link = createHelpLink(app, networkLayout, "Learn more about pretrained networks", "PretrainedNetwork");
            link.Layout.Column = [1 3];

            % Buttons for viewing network in Analyzer or Deep Network
            % Designer.
            uibutton(networkLayout, Text="Analyze", Tag = "ANALYZE_NETWORKBUTTON", ...
                ButtonPushedFcn=@app.handleAnalyzeButtonPushed);
            uibutton(networkLayout, Text="Visualize", Tag = "VISUALIZE_NETWORKBUTTON", ...
                ButtonPushedFcn=@app.handleDNDButtonPushed);

            % Set a default network
            setNetworkFromPretrained(app.Model, "squeezenet");
        end

        function createWorkspaceNetworkSettings(app, parent, netName)
            % Create settings for configuring a workspace imported network.
            %
            % This is just training options; there are no network settings
            % to tweak.

            % Network settings
            networkLayout = uigridlayout(parent, ...
                RowHeight={'fit', 'fit', 'fit', 'fit', 'fit'}, ...
                ColumnWidth={'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);
            l = uilabel(networkLayout, Text="Network", FontWeight="bold");
            l.Layout.Column = [1 2];

            uilabel(networkLayout, Text="Network");
            uilabel(networkLayout, Text=netName);

            % Buttons for viewing network in Analyzer or Deep Network
            % Designer.
            uibutton(networkLayout, Text="Analyze", ...
                ButtonPushedFcn=@app.handleAnalyzeButtonPushed);
            uibutton(networkLayout, Text="Visualize", ...
                ButtonPushedFcn=@app.handleDNDButtonPushed);

            setNetworkFromWorkspace(app.Model, netName);
        end

        function createNetworkTrainingResultsSection(app, parent)
            % Create widgets for holding the trained network results.

            % Post-training metrics
            resultsLayout = uigridlayout(parent, ...
                RowHeight={'fit', 'fit', 'fit', 'fit', 'fit'}, ...
                ColumnWidth={'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);
            titleLabel = uilabel(resultsLayout, Text="Training Results", FontWeight="bold");
            titleLabel.Layout.Column = [1 2];

            % Additional info on how to train, as these widgets are not
            % populated until after training.
            infoLabel = uilabel(resultsLayout, ...
                Text="⏳ Click Train to train model", ...
                FontAngle="italic");
            infoLabel.Layout.Column = [1 2];
            app.NetworkSettingsWidgets.MetricsInfoLabel = infoLabel;

            % Validation accuracy and loss info
            uilabel(resultsLayout, Text="Accuracy (validation)");
            app.NetworkSettingsWidgets.ValidationAccuracyLabel = uilabel(resultsLayout, ...
                Text="Train network", FontAngle="italic");
            uilabel(resultsLayout, Text="Loss (validation)");
            app.NetworkSettingsWidgets.ValidationLossLabel = uilabel(resultsLayout, ...
                Text="Train network", FontAngle="italic");

            link = createHelpLink(app, resultsLayout, "Deep learning tips and tricks", "DeepLearningTipsAndTricks");
            link.Layout.Column = [1 2];
        end

        function createBasicTrainingOptions(app, parent)
            % Create widgets for holding the basic training options: learn
            % rate, max epochs, minibatch size, execution environment.

            % Basic training options
            numRows = 7;
            basicTrainingOptsLayout = uigridlayout(parent, ...
                RowHeight=repmat({'fit'}, numRows, 1), ...
                ColumnWidth={'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);
            lbl = uilabel(basicTrainingOptsLayout, Text="Training Options", FontWeight="bold");
            lbl.Layout.Column = [1 2];

            % Initial learn rate
            uilabel(basicTrainingOptsLayout, Text="Initial learn rate", Tooltip=app.Guide.getHelpFor("InitialLearnRate"));
            app.TrainingOptionssWidgets.InitialLearnRateSpinner = uispinner(basicTrainingOptsLayout, ...
                Value=0.001, Limits=[0 1000], Step=0.001, ...
                Tooltip=app.Guide.getHelpFor("InitialLearnRate"), ...
                ValueChangedFcn=@app.handleTrainingOptionsChanged);

            % Max epochs
            uilabel(basicTrainingOptsLayout, Text="Max epochs", Tooltip=app.Guide.getHelpFor("MaxEpochs"));
            app.TrainingOptionssWidgets.MaxEpochsSpinner = uispinner(basicTrainingOptsLayout, ...
                Value=30, Limits=[1 1e6], Step=1, RoundFractionalValues="on", ...
                Tooltip=app.Guide.getHelpFor("MaxEpochs"), ...
                Tag="MODELDOCUMENT_MAXEPOCHSSPINNER", ...
                ValueChangedFcn=@app.handleTrainingOptionsChanged);

            % Minibatch size
            uilabel(basicTrainingOptsLayout, Text="Minibatch size", Tooltip=app.Guide.getHelpFor("MinibatchSize"));
            app.TrainingOptionssWidgets.MinibatchSizeSpinner = uispinner(basicTrainingOptsLayout, ...
                Value=32, Limits=[1 1e5], Step=16, RoundFractionalValues="on", ...
                Tooltip=app.Guide.getHelpFor("MinibatchSize"), ...
                ValueChangedFcn=@app.handleTrainingOptionsChanged);

            % Execution environment
            uilabel(basicTrainingOptsLayout, Text="Execution environment", Tooltip=app.Guide.getHelpFor("ExecutionEnvironent"));
            app.TrainingOptionssWidgets.ExecutionEnvironmentDropdown = uidropdown(basicTrainingOptsLayout, ...
                Items = ["auto", "gpu", "cpu"], ...
                Tooltip=app.Guide.getHelpFor("ExecutionEnvironent"), ...
                ValueChangedFcn=@app.handleTrainingOptionsChanged);

            link = createHelpLink(app, basicTrainingOptsLayout, "Learn more about training options", "TrainingOptions");
            link.Layout.Column = [1 2];
        end

        function createAdvancedTrainingOptions(app, parent)
            % Create widgets for holding more advanced training options,
            % e.g. solver, validation frequency

            % Layout with checkbox to toggle visibility
            layout = uigridlayout(parent, ...
                RowHeight={'fit', 'fit'}, ...
                ColumnWidth={'1x'}, ...
                Padding=0, ...
                RowSpacing=5);

            app.TrainingOptionssWidgets.showAdvancedOptsCheckbox = uicheckbox(layout, Value=0, Text="Show advanced options");
            advancedOptionsLayout = uigridlayout(layout, ...
                RowHeight={'fit', 'fit', 'fit'}, ...
                ColumnWidth={'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5, ...
                Visible=false);

            % Capture the layout in an anonymous function toggling its
            % visibility
            app.TrainingOptionssWidgets.showAdvancedOptsCheckbox.ValueChangedFcn = @(s,e)( ...
                set(advancedOptionsLayout, "Visible", ~advancedOptionsLayout.Visible));
            
            % Solver
            uilabel(advancedOptionsLayout, Text="Solver");
            app.TrainingOptionssWidgets.SolverDropdown = uidropdown(advancedOptionsLayout, ...
                Items=["sgdm", "rmsprop", "adam"], ...
                ValueChangedFcn=@app.handleTrainingOptionsChanged);

            % Validation frequency
            uilabel(advancedOptionsLayout, Text="Validation frequency");
            app.TrainingOptionssWidgets.ValidationFrequencySpinner = uispinner(advancedOptionsLayout, ...
                Value=10, Limits=[1 1e5], Step=10, RoundFractionalValues="on", ...
                ValueChangedFcn=@app.handleTrainingOptionsChanged);     
        end        

        function updateTrainingOptions(app)
            % Update the training options on the model based on the current
            % widget values.

            widgets = app.TrainingOptionssWidgets;
            solver = app.TrainingOptionssWidgets.SolverDropdown.Value;

            % Pull values off the widgets onto a struct, then used
            % namedargs2cell to convert that struct to a cell array of args
            opts = struct();
            opts.InitialLearnRate = widgets.InitialLearnRateSpinner.Value;
            opts.MaxEpochs = widgets.MaxEpochsSpinner.Value;
            opts.MiniBatchSize = widgets.MinibatchSizeSpinner.Value;
            opts.ValidationFrequency = widgets.ValidationFrequencySpinner.Value;
            opts.ExecutionEnvironment = widgets.ExecutionEnvironmentDropdown.Value;

            args = namedargs2cell(opts);
            updateTrainingOptions(app.Model, solver, args{:});
        end

        function handleNetworkDropdownChanged(app, ~, ~)
            % Respond to a change in the network selection dropdown.

            setNetworkFromPretrained(app.Model, ...
                app.NetworkSettingsWidgets.NetworkDropdown.Value);
        end

        function handleAnalyzeButtonPushed(app, ~, ~)
            % When Analyze button is clicked, display the net with
            % analyzeNetwork.

            [~, cleaner] = showProgressDlg(app, "Analyzing network...");
            analyzeNetwork(app.Model.UntrainedNetwork);
        end

        function handleDNDButtonPushed(app, ~, ~)
            % When DND button is clicked, display the net with
            % deepNetworkDesigner.

            [~, cleaner] = showProgressDlg(app, "Opening in Deep Network Designer...");
            deepNetworkDesigner(app.Model.UntrainedNetwork);
        end

        function handleTrainingOptionsChanged(app, ~, ~)
            updateTrainingOptions(app);
        end

        function handleImageIndexDropdownChanged(app, sdr, evt)
            displayDatasetImagePrediction(app, "validation", evt.Value)
        end

        function handlePredictOnFileEditFieldChanged(app, sdr, evt)
            displayPredictOnFilePrediction(app, evt.Value);
        end


        function displayDatasetImagePrediction(app, dataSplit, imageIndex)
            % Display the results of predicting on an image from the
            % dataset, showing the image, the predicted class, and the
            % class score distribution.

            % Generate prediction results.
            results = predict(app.Model, dataSplit, imageIndex);
            app.PredictionResults = results;

            displayImagePrediction(app, results);
        end

        function displayPredictOnFilePrediction(app, filePath)
            % Display the results of predicting on an image given a file
            % path.

            % This can easily error if the user provides an invalid path.
            % Catch and display that error.
            try
                img = imread(filePath);
            catch err
                % Most likely, the file path is not a valid image to read
                % with imread.
                uialert(app.UIFigure, err.message, "Failed to read image");
                return;
            end

            results = predictOnImage(app.Model, img);
            displayImagePrediction(app, results);
        end

        function displayImagePrediction(app, results)
            % Display the results of predicting on an image

            % Plot image
            imgAxes = app.ImagePredictionWidgets.ImageAxes;
            imshow(results.Image, Parent=imgAxes);
            title(imgAxes, sprintf("%s (%0.2f)", ...
                results.PredictedLabel, results.PredictedLabelScore));
            imgAxes.Title.Interpreter = "none";

            % Plot bar graph of class scores
            classScoreAxes = app.ImagePredictionWidgets.ClassScoreAxes;
            bar(classScoreAxes, results.Scores);
            title(classScoreAxes, "Class Score");
            ylabel(classScoreAxes, "Score");
            xticklabels(classScoreAxes, string(results.Labels));

            classScoreAxes.XAxis.TickLabelInterpreter = "none";
        end

        function handleRunInterpretabilityPushed(app, ~, ~)
            % Respond to the user asking for interpretability to be
            % computed.

            technique = app.InterpretabilityWidgets.TechniqueDropdown.Value;
            idx = app.InterpretabilityWidgets.ImageIndexDropdown.Value;
            app.displayInterpretability("validation", idx, technique);
        end

        function displayInterpretability(app, dataSplit, imageIndex, technique)
            % Display the interpretability map for a given set of data.

            [~, cleaner] = showProgressDlg(app, sprintf("Computing interpretability map for <code>%s</code>...", technique));

            % Clean up old plots
            ax = app.InterpretabilityWidgets.ImageAxes;
            delete(ax.Children);

            results = interpret(app.Model, dataSplit, imageIndex, technique);
            app.InterpretabilityResults = results;

            % Plot image
            imshow(results.Image, Parent=ax);
            ax.Title.Interpreter = "none";

            % Plot interpretability map
            hold(ax, "on");
            imagesc(results.Map, Parent=ax, AlphaData=0.5);
            colormap(ax, "jet");
            title(ax, sprintf("%s (%0.2f %%)", ...
                results.PredictedLabel, 100*results.PredictedLabelScore));
        end

        function handleInterpretImageIndexDropdownChanged(app, sdr, evt)
            % No-op - we wait for the user to click to run
            % interpretability.
        end

        function handleInterpretTechniqueDropdownChanged(app, sdr, evt)
            % No-op - we wait for the user to click to run
            % interpretability.
        end

        function updateNetworkEditorPostTraining(app, metrics)
            % Update the network editor document when training has
            % finished, e.g. updating metric values.

            app.NetworkSettingsWidgets.MetricsInfoLabel.Text = "✅ Training complete";
            app.NetworkSettingsWidgets.MetricsInfoLabel.FontAngle = "normal";

            app.NetworkSettingsWidgets.ValidationAccuracyLabel.Text = ...
                sprintf("%.2f %%", metrics.ValidationAccuracy);
            app.NetworkSettingsWidgets.ValidationAccuracyLabel.FontAngle = "normal";

            app.NetworkSettingsWidgets.ValidationLossLabel.Text =  ...
                sprintf("%.4f", metrics.ValidationLoss);
            app.NetworkSettingsWidgets.ValidationLossLabel.FontAngle = "normal";
        end

        function [hDlg, cleaner] = showProgressDlg(app, msg)
            % Display an indeterminate progress dialog. Return a handle to
            % it, and a handle to an onCleanup object. When the onCleanup
            % is deleted in the calling function by going out of scope, the
            % dialog will be closed.

            hDlg = uiprogressdlg(app.UIFigure, Indeterminate="on", Message=msg, Interpreter="html");
            cleaner = onCleanup(@()delete(hDlg));
        end

        function tab = addOrSelectTab(app, tabName)
            % Either add a tab with the given name if none exists, or
            % select it if it does exist.

            tabID = matlab.lang.makeValidName(tabName);

            if isfield(app.AddedTabs, tabID)
                % We already have the tab
                tab = app.AddedTabs.(tabID);
            else
                % We need to create the tab
                tab = uitab(app.MainTabGroup, Title=tabName);
                app.AddedTabs.(tabID) = tab;
            end

            app.MainTabGroup.SelectedTab = tab;
        end

        function w = createHelpWidget(app, parent, topic)
            % Create a help widget displaying useful guidance.

            w = uilabel(parent, Text="️️❓ Info", ...
                Tooltip=app.Guide.getHelpFor(topic), FontAngle="italic");
        end

        function w = createHelpLink(app, parent, str, topic)
            % Create a help link linking to more resources.

            webPage = app.Guide.getWebLinkFor(topic);
            w = uihyperlink(parent, Text=str, URL=webPage);
        end
        
        function disablePostDataImport(app)
            % Disable all widgets after data import, as the app workflow is
            % being reset.
            
            setLayoutButtonEnablement(app, app.ModelSectionLayout, false)
            setLayoutButtonEnablement(app, app.TrainSectionLayout, false)
            setLayoutButtonEnablement(app, app.PlotSectionLayout, false)
            setLayoutButtonEnablement(app, app.ExportSectionLayout, false)
        end

        function setLayoutButtonEnablement(~, layout, isEnabled)
            % Change the enablement of all buttons in a container.

            sectionButtons = findall(layout, Type="uibutton");

            for i=1:length(sectionButtons)
                sectionButtons(i).Enable = isEnabled;
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Create model objects
            app.Model = ic.Model();
            app.Guide = ic.Guide();

            % Disable toolstrip buttons
            disablePostDataImport(app);

            % Toolstrip button tooltips
            app.ImportDatafromFolderButton.Tooltip = app.Guide.getHelpFor("ImportFromFolder");
            app.LoadExampleDataButton.Tooltip = app.Guide.getHelpFor("LoadExampleData");
            app.QuickStartButton.Tooltip = app.Guide.getHelpFor("QuickTrainNetwork");
            app.ChoosePretrainedButton.Tooltip = app.Guide.getHelpFor("FromAppNetwork");
            app.FromWorkspaceButton.Tooltip = app.Guide.getHelpFor("FromWorkspaceNetwork");
            app.BuildNewButton.Tooltip = app.Guide.getHelpFor("FromScratchNetwork");

            % Toolstrip label dark theming.
            %
            % Note this won't handle theme changes, only opening with
            % existing theme.
            app.DATALabel.BackgroundColor = app.UIFigure.Color;
            app.MODELLabel.BackgroundColor = app.UIFigure.Color;
            app.TRAINLabel.BackgroundColor = app.UIFigure.Color;
            app.PLOTSANDRESULTSLabel.BackgroundColor = app.UIFigure.Color;
            app.EXPORTLabel.BackgroundColor = app.UIFigure.Color;
        end

        % Button pushed function: ImportDatafromFolderButton
        function ImportDatafromFolderButtonPushed(app, event)
            
            % Select the folder of class folders with a dialog.
            folderPath = uigetdir(pwd);

            % Handle case we cancelled
            if isequal(folderPath, 0)
                return;
            end

            try
                setDataFromFilePath(app.Model, string(folderPath));
            catch err
                uialert(app.UIFigure, err.getReport("basic", "hyperlinks", "off"), "Image Classifier");
            end

            % Display the data
            plotData(app);

            % Enable next workflow step
            setLayoutButtonEnablement(app, app.ModelSectionLayout, true);

            % Make sure data tab is visible
            app.MainTabGroup.SelectedTab = app.DataTab;
        end

        % Button pushed function: QuickStartButton
        function QuickStartButtonPushed(app, event)
            [~, cleaner] = app.showProgressDlg("Creating network...");
            createQuickTrainNetworkEditor(app);

            % Enable next workflow step
            setLayoutButtonEnablement(app, app.TrainSectionLayout, true);

            % Make sure model tab is visible
            app.MainTabGroup.SelectedTab = app.ModelTab;
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            
            % Show progress dialog with a tip
            dlgText = sprintf("Training network...\n\nTip: %s", ...
                app.Guide.getTip());
            [h, cleaner] = app.showProgressDlg(dlgText);

            try
                train(app.Model);
            catch err
                % Training unexpectedly errored.
                delete(h);
                uialert(app.UIFigure, ...
                    sprintf("Training failed. Error:\n\n%s", err.message), ...
                    "Training failed", Icon="error");
                return;
            end

            % Pull out training results
            tr = app.Model.TrainingResults;

            % Grab loss and accuracy for the returned network (which may
            % not be the network at the last iteration).
            idx = find(tr.OutputNetworkIteration == tr.ValidationHistory.Iteration);
            metrics = struct( ...
                ValidationLoss = tr.ValidationHistory{idx, "Loss"}, ...
                ValidationAccuracy = tr.ValidationHistory{idx, "Accuracy"});

            % Update network editor with results
            updateNetworkEditorPostTraining(app, metrics);

            % Refocus the app window
            figure(app.UIFigure);

            setLayoutButtonEnablement(app, app.PlotSectionLayout, true);
            setLayoutButtonEnablement(app, app.ExportSectionLayout, true);

            % After training has finished, show info dialog
            delete(h);
            trainingResultText = sprintf( ...
                "Training finished.\n\nFinal validation accuracy: %.2f %%", metrics.ValidationAccuracy);
            uialert(app.UIFigure, trainingResultText, "Training finished", Icon="success");
        end

        % Button pushed function: ExporttoWorkspaceButton
        function ExporttoWorkspaceButtonPushed(app, event)
            s = getStructForExport(app.Model);

            % Push the exported info into the base workspace
            varName = "model";
            assignin("base", varName, s);

            uialert(app.UIFigure, "Exported trained model and training " + ...
                "results to workspace as <code>model</code>.", "", ...
                Icon="success", ...
                Interpreter="html");
        end

        % Button pushed function: ConfusionMatrixButton
        function ConfusionMatrixButtonPushed(app, event)
            
            % Compute confusion matrix data
            dataSplit = "validation";
            results = confusionMatrix(app.Model, dataSplit);
            app.ConfusionMatrixResults = results;

            tab = addOrSelectTab(app, "Confusion Matrix");

            % Plot confusionchart
            layout = uigridlayout(tab, [1 1]);
            confusionchart(layout, results.ConfusionMatrix, ...
                string(results.ClassLabels));
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            tab = addOrSelectTab(app, "Predict");

            % Delete any existing widgets
            delete(tab.Children);

            % Layouts for holding widgets
            mainLayout = uigridlayout(tab, ...
                RowHeight={'fit', 'fit'}, ...
                ColumnWidth={'1x'});
            controlsLayout = uigridlayout(mainLayout, ...
                RowHeight={'fit', 'fit', 'fit'}, ...
                ColumnWidth={'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);
            axesLayout = uigridlayout(mainLayout, ...
                RowHeight={'fit'}, ColumnWidth={'1x', '1x'}, ...
                Padding=0);

            % Widgets for predicting on image in dataset.
            uilabel(controlsLayout, Text="Data split");
            uilabel(controlsLayout, Text="Validation");
            uilabel(controlsLayout, Text="Image index");
            imageDropdown = uidropdown(controlsLayout, ...
                Items = app.Model.ValidationLabels, ...
                ItemsData = 1:length(app.Model.ValidationLabels), ...
                ValueChangedFcn=@app.handleImageIndexDropdownChanged);

            % Predict on image from file
            uilabel(controlsLayout, Text="From file");
            filePredictEditField = uieditfield(controlsLayout, Placeholder="Enter file path of image", ...
                ValueChangedFcn=@app.handlePredictOnFileEditFieldChanged);

            % Display results
            imgAxes = axes(axesLayout);
            barAxes = axes(axesLayout);

            % Avoid "_" creating underscores
            barAxes.XAxis.TickLabelInterpreter = "none";

            app.ImagePredictionWidgets = struct( ...
                ImageIndexDropdown = imageDropdown, ...
                ImageFilePredictEditField = filePredictEditField, ...
                ImageAxes = imgAxes, ...
                ClassScoreAxes = barAxes);

            displayDatasetImagePrediction(app, "validation", 1);
        end

        % Button pushed function: LoadExampleDataButton
        function LoadExampleDataButtonPushed(app, event)
            
            % Load data
            appLocation = which("ImageClassifier.mlapp");
            appFolder = fileparts(appLocation);
            setDataFromFilePath(app.Model, fullfile(appFolder, "Data"));

            % Display results
            plotData(app);

            % Enable next step of workflow
            setLayoutButtonEnablement(app, app.ModelSectionLayout, true);

            % Make sure data tab is visible
            app.MainTabGroup.SelectedTab = app.DataTab;
        end

        % Button pushed function: InterpretButton
        function InterpretButtonPushed(app, event)
            
            tab = addOrSelectTab(app, "Interpret");

            % Clear any existing widgets
            delete(tab.Children);

            % Create layouts to hold widgets
            mainLayout = uigridlayout(tab, ...
                RowHeight={'fit', 'fit'}, ...
                ColumnWidth={'1x'});
            controlsLayout = uigridlayout(mainLayout, ...
                RowHeight={'fit', 'fit','fit', 'fit', 'fit'}, ...
                ColumnWidth={'fit', 'fit'}, ...
                Padding=0, ...
                RowSpacing=5);

            uilabel(controlsLayout, Text="Data split");
            uilabel(controlsLayout, Text="Validation");
            uilabel(controlsLayout, Text="Image index");
            imageDropdown = uidropdown(controlsLayout, ...
                Items = app.Model.ValidationLabels, ...
                ItemsData = 1:length(app.Model.ValidationLabels), ...
                ValueChangedFcn=@app.handleInterpretImageIndexDropdownChanged);

            uilabel(controlsLayout, Text="Technique");
            d = uidropdown(controlsLayout, ...
                Items=["gradCAM", "occlusionSensitivity", "imageLIME"], ...
                ValueChangedFcn=@app.handleInterpretTechniqueDropdownChanged);

            % We don't auto-run interpretability techniques; instead we
            % wait for the user to run it.
            btn = uibutton(controlsLayout, Text="Interpret image", ...
                Tag = "RUN_INTERPRETABILITYBUTTON", ...
                ButtonPushedFcn=@app.handleRunInterpretabilityPushed);
            btn.Layout.Column = [1 2];

            link = createHelpLink(app, controlsLayout, "Learn more about interpretability", "Interpretability");
            link.Layout.Column = [1 2];

            imgAxes = axes(mainLayout);

            app.InterpretabilityWidgets = struct( ...
                ImageIndexDropdown = imageDropdown, ...
                ImageAxes = imgAxes, ...
                TechniqueDropdown = d);

            % Display a default interpretability computation
            app.displayInterpretability("validation", 1, "gradCAM");
        end

        % Callback function
        function PredictonwebURLButtonPushed(app, event)
            %No-op - no longer accessible
        end

        % Button pushed function: FromWorkspaceButton
        function FromWorkspaceButtonPushed(app, event)
            
            % Ask the user which workspace network to import.
            prompt = sprintf("Select a <code>dlnetwork</code> object with %i output classes from the workspace.", app.Model.NumClasses);
            chosenNetwork = ic.variablePickerDialog("Choose workspace network", ...
                prompt, ...
                @(x)strcmp(x.Class, "dlnetwork"), IsTesting = app.IsTesting);

            % Bail out early if the user cancelled.
            if isempty(chosenNetwork)
                return;
            end

            % The user selected a network; we need to check if it's valid.
            netName = string(chosenNetwork);

            try
                % We might throw errors if the network isn't valid for this
                % dataset.
                validateWorkspaceNetwork(app.Model, netName);
                doImport = true;
            catch err
                uialert(app.UIFigure, err.message, ...
                    "Error importing network", Icon="error", Interpreter="html");
                doImport = false;
            end

            % If the network's valid, import it.
            if doImport
                setNetworkFromWorkspace(app.Model, netName);
                [~, cleaner] = app.showProgressDlg("Importing network...");
                createWorkspaceImportNetworkEditor(app, netName);

                % Make sure model tab is visible
                app.MainTabGroup.SelectedTab = app.ModelTab;

                % Enable next step of workflow
                setLayoutButtonEnablement(app, app.TrainSectionLayout, true);

                uialert(app.UIFigure, sprintf("Imported network '%s'.", netName), ...
                    "Network imported", Icon="success");
            end
        end

        % Button pushed function: ChoosePretrainedButton
        function ChoosePretrainedButtonPushed(app, event)
            
            % Show a simple list of steps to follow to prepare the network
            % in DND.
            explanation = sprintf("In Deep Network Designer, prepare a network which outputs %i classes.\n\n" + ...
                "<ul>" + ...
                "<li>Modify a pretrained image network, or build a network from scratch.</li>" + ...
                "<li>Configure the network for classification on your data (%i channels output from the last fully-connected or convolutional layer.</li>" + ...
                "<li>Export from Deep Network Designer to the workspace (<b>Export > Export</b>)</li>" + ...
                "<li>Import the network from the workspace here (<b>Model > From Workspace</b>)</li>" + ...
                "</ul>", ...
                app.Model.NumClasses, app.Model.NumClasses);
            uialert(app.UIFigure, explanation, "Prepare network in Deep Network Designer", ...
                Icon="info", Interpreter="html");

            % Open DND with no input so the user gets the start page if
            % it's not already open.
            deepNetworkDesigner();
        end

        % Button pushed function: GenerateCodeButton
        function GenerateCodeButtonPushed(app, event)
            
            % Generate training code
            code = generateCodeForTraining(app.Model);
            code = join(code, newline);

            % Put it in a new editor document
            doc = matlab.desktop.editor.newDocument();
            doc.Text = code;
            smartIndentContents(doc);
        end

        % Button pushed function: ImportDatastorefromworkspaceButton
        function ImportDatastorefromworkspaceButtonPushed(app, event)

            % Ask the user which workspace datastore to import.
            prompt = "Select an image datastore from the workspace.";
            chosenDatastore = ic.variablePickerDialog("Choose workspace data", ...
                prompt, ...
                @(x)strcmp(x.Class, "matlab.io.datastore.ImageDatastore"), IsTesting = app.IsTesting);
            
            if isempty(chosenDatastore)
                return;
            end

            % Check datastore is valid for import
            try
                validateWorkspaceDatastore(app.Model, chosenDatastore);
                doImport = true;
            catch err
                uialert(app.UIFigure, err.message, ...
                    "Error importing image datastore", Icon="error", Interpreter="html");
                doImport = false;
            end

            % Datastore is valid; proceed with import.
            if doImport
                setDataFromWorkspace(app.Model, chosenDatastore);
                plotData(app);
                setLayoutButtonEnablement(app, app.ModelSectionLayout, true);
            end
        end

        % Button pushed function: BuildNewButton
        function BuildNewButtonPushed(app, event)
            
            % Show a simple list of steps to follow to prepare the network
            % in DND.
            explanation = sprintf("In Deep Network Designer, prepare a network which outputs %i classes.\n\n" + ...
                "<ul>" + ...
                "<li>Build a network from scratch, using the template layers.</li>" + ...
                "<li>Configure the network for classification on your data (%i channels output from the last fully-connected or convolutional layer).</li>" + ...
                "<li>Export from Deep Network Designer to the workspace (<b>Export > Export</b>)</li>" + ...
                "<li>Import the network from the workspace here (<b>Model > From Workspace</b>>)</li>" + ...
                "</ul>", ...
                app.Model.NumClasses, app.Model.NumClasses);

            uialert(app.UIFigure, explanation, "Prepare network in Deep Network Designer", ...
                Icon="info", Interpreter="html");

            % Create a template network with the right size inputs and
            % outputs, and open it in DND.
            templateNet = createTemplateNetwork(app.Model);
            deepNetworkDesigner(templateNet);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Classifier App';
            app.UIFigure.Tag = 'IMAGECLASSIFIER_UIFIGURE';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.MainTabGroup = uitabgroup(app.MainLayout);
            app.DataTab = uitab(app.MainTabGroup);
            app.DataTab.Title = 'Data';
            app.GridLayout3 = uigridlayout(app.DataTab);
            app.ModelTab = uitab(app.MainTabGroup);
            app.ModelTab.Title = 'Model';
            app.GridLayout4 = uigridlayout(app.MainLayout);
            app.ToolstripLayout = uigridlayout(app.GridLayout4);
            app.ModelSectionLayout = uigridlayout(app.ToolstripLayout);
            app.ChoosePretrainedButton = uibutton(app.ModelSectionLayout, 'push');
            app.ChoosePretrainedButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosePretrainedButtonPushed, true);
            app.ChoosePretrainedButton.Text = {'🛠️ '; 'Choose'; 'Pretrained'};
            app.GridLayout = uigridlayout(app.ModelSectionLayout);
            app.QuickStartButton = uibutton(app.GridLayout, 'push');
            app.QuickStartButton.ButtonPushedFcn = createCallbackFcn(app, @QuickStartButtonPushed, true);
            app.QuickStartButton.Tag = 'MODEL_PRETRAINEDNETWORK';
            app.QuickStartButton.Text = '⚡Quick Start';
            app.FromWorkspaceButton = uibutton(app.GridLayout, 'push');
            app.FromWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @FromWorkspaceButtonPushed, true);
            app.FromWorkspaceButton.Text = '🗃️ From Workspace';
            app.BuildNewButton = uibutton(app.GridLayout, 'push');
            app.BuildNewButton.ButtonPushedFcn = createCallbackFcn(app, @BuildNewButtonPushed, true);
            app.BuildNewButton.Text = '✏️ Build New';
            app.TrainSectionLayout = uigridlayout(app.ToolstripLayout);
            app.TrainButton = uibutton(app.TrainSectionLayout, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Tag = 'TRAIN_TRAINBUTTON';
            app.TrainButton.Text = {'🏋️'; 'Train'};
            app.PlotSectionLayout = uigridlayout(app.ToolstripLayout);
            app.PredictButton = uibutton(app.PlotSectionLayout, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = {'📊'; 'Predict'};
            app.ConfusionMatrixButton = uibutton(app.PlotSectionLayout, 'push');
            app.ConfusionMatrixButton.ButtonPushedFcn = createCallbackFcn(app, @ConfusionMatrixButtonPushed, true);
            app.ConfusionMatrixButton.Text = {'🧮'; 'Confusion '; 'Matrix'};
            app.InterpretButton = uibutton(app.PlotSectionLayout, 'push');
            app.InterpretButton.ButtonPushedFcn = createCallbackFcn(app, @InterpretButtonPushed, true);
            app.InterpretButton.Text = {'🔍'; 'Interpret'};
            app.ExportSectionLayout = uigridlayout(app.ToolstripLayout);
            app.ExporttoWorkspaceButton = uibutton(app.ExportSectionLayout, 'push');
            app.ExporttoWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExporttoWorkspaceButtonPushed, true);
            app.ExporttoWorkspaceButton.Tag = 'EXPORT_WORKSPACEBUTTON';
            app.ExporttoWorkspaceButton.Text = {'📤'; 'Export to'; 'Workspace'};
            app.GenerateCodeButton = uibutton(app.ExportSectionLayout, 'push');
            app.GenerateCodeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateCodeButtonPushed, true);
            app.GenerateCodeButton.Text = {'📜'; 'Generate'; ' Code'};
            app.DATALabel = uilabel(app.ToolstripLayout);
            app.DATALabel.Text = 'DATA';
            app.MODELLabel = uilabel(app.ToolstripLayout);
            app.MODELLabel.Text = 'MODEL';
            app.TRAINLabel = uilabel(app.ToolstripLayout);
            app.TRAINLabel.Text = 'TRAIN';
            app.PLOTSANDRESULTSLabel = uilabel(app.ToolstripLayout);
            app.PLOTSANDRESULTSLabel.Text = 'PLOTS AND RESULTS';
            app.EXPORTLabel = uilabel(app.ToolstripLayout);
            app.EXPORTLabel.Text = 'EXPORT';
            app.DataSectionLayout = uigridlayout(app.ToolstripLayout);
            app.ImportDatafromFolderButton = uibutton(app.DataSectionLayout, 'push');
            app.ImportDatafromFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatafromFolderButtonPushed, true);
            app.ImportDatafromFolderButton.Interpreter = 'html';
            app.ImportDatafromFolderButton.Text = {'📂'; 'Import Data'; 'from Folder'};
            app.GridLayout2 = uigridlayout(app.DataSectionLayout);
            app.LoadExampleDataButton = uibutton(app.GridLayout2, 'push');
            app.LoadExampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExampleDataButtonPushed, true);
            app.LoadExampleDataButton.Tag = 'DATA_EXAMPLEDATABUTTON';
            app.LoadExampleDataButton.Text = '📝 Load Example Data';
            app.ImportDatastorefromworkspaceButton = uibutton(app.GridLayout2, 'push');
            app.ImportDatastorefromworkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDatastorefromworkspaceButtonPushed, true);
            app.ImportDatastorefromworkspaceButton.Text = '🖼️ From Workspace';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageClassifier

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end